classdef GUI_avatarAlfa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        GridLayout5            matlab.ui.container.GridLayout
        UIAxes                 matlab.ui.control.UIAxes
        GridLayout3            matlab.ui.container.GridLayout
        BienvenidoaSMRLabel    matlab.ui.control.Label
        GridLayout2            matlab.ui.container.GridLayout
        GridLayout7            matlab.ui.container.GridLayout
        VisualizaciondemovimientosLabel  matlab.ui.control.Label
        PosicioninicialButton  matlab.ui.control.Button
        BajadaButton           matlab.ui.control.Button
        SubidaButton           matlab.ui.control.Button
        GridLayout6            matlab.ui.container.GridLayout
        ApellidosLabel2        matlab.ui.control.Label
        ApellidosLabel         matlab.ui.control.Label
        FechaLabel2            matlab.ui.control.Label
        PesoLabel2             matlab.ui.control.Label
        EdadLabel2             matlab.ui.control.Label
        NombreLabel2           matlab.ui.control.Label
        FechaLabel             matlab.ui.control.Label
        PesoLabel              matlab.ui.control.Label
        EdadLabel              matlab.ui.control.Label
        NombreLabel            matlab.ui.control.Label
        GridLayout4            matlab.ui.container.GridLayout
        VideoButton            matlab.ui.control.Button
        SensoresButton         matlab.ui.control.Button
        GraficasButton         matlab.ui.control.Button
        InformeButton          matlab.ui.control.Button
        ImageLogo              matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global nombre apellidos edad peso;
            %load datos.mat nombre apellido edad peso;

            app.NombreLabel2.Text = strcat(nombre);
            app.ApellidosLabel2.Text = strcat(apellidos);
            app.EdadLabel2.Text = strcat(int2str(edad), " años");
            app.PesoLabel2.Text = strcat(num2str(peso) , "kg");

            formato = 'dd/mm/yy';
            app.FechaLabel2.Text = datestr(now,formato);
        end

        % Button pushed function: SubidaButton
        function SubidaButtonPushed(app, event)
            main;
            fm =10;

            % Movimiento progresivo de subida
            for ii = 1:size(y_cs,2)
                Rtorso = rotationMatrix(0,0,0);

                cla(app.UIAxes);
                tic
                torsoRotado = applyRotationNx3(torso,sum(torso)/8,Rtorso);
                torsoRotado1 = applyRotationNx3(torso1,sum(torso)/8,Rtorso);

                patch(app.UIAxes,'faces',f,'vertices',torsoRotado,'FaceVertexCData',color,'FaceColor','flat');
                patch(app.UIAxes,'faces',f,'vertices',torsoRotado1,'FaceVertexCData',color,'FaceColor','flat');

                Rcadera = rotationMatrix(0,y_cs(ii),0);

                piernaSuperiorRotada1 = applyRotationNx3(applyRotationNx3(piernaSuperior1,...
                    (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                piernaSuperiorRotada2 = applyRotationNx3(applyRotationNx3(piernaSuperior2,...
                    (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);

                patch(app.UIAxes,'faces',f,'vertices',piernaSuperiorRotada1,'FaceVertexCData',color,'FaceColor','flat');
                patch(app.UIAxes,'faces',f,'vertices',piernaSuperiorRotada2,'FaceVertexCData',color,'FaceColor','flat');

                %         for jj = 1:size(y_rs,2)
                Rrodilla = rotationMatrix(0,y_rs(ii),0);

                piernaInferiorRotada1 = applyRotationNx3(applyRotationNx3(applyRotationNx3(Pierna1,...
                    sum(piernaSuperior1(1:4,:))/4,Rrodilla),(torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                piernaInferiorRotada2 = applyRotationNx3(applyRotationNx3(applyRotationNx3(Pierna2,...
                    sum(piernaSuperior1(1:4,:))/4,Rrodilla),(torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                piernaInferiorRotada3 = applyRotationNx3(applyRotationNx3(applyRotationNx3(Pierna3,...
                    sum(piernaSuperior1(1:4,:))/4,Rrodilla),(torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);

                patch(app.UIAxes,'faces',f,'vertices',piernaInferiorRotada1,'FaceVertexCData',color,'FaceColor','flat');
                patch(app.UIAxes,'faces',f,'vertices',piernaInferiorRotada2,'FaceVertexCData',color,'FaceColor','flat');
                patch(app.UIAxes,'faces',f,'vertices',piernaInferiorRotada3,'FaceVertexCData',color,'FaceColor','flat');

                %                 for kk = 1:size(y_ts,2)
                Rtobillo = rotationMatrix(0,y_ts(ii),0);

                pieTalonRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(pieDTalon,...
                    sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                    (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                piePuntaRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(pieDPunta,...
                    sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                    (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                dedoPulgarRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoGordo,...
                    sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                    (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                dedoIndiceRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoIndice,...
                    sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                    (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                dedoCorazonRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoCorazon,...
                    sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                    (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                dedoAnularRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoAnular,...
                    sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                    (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                dedoMeniqueRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoMenique,...
                    sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                    (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);

                patch(app.UIAxes,'faces',f,'vertices',pieTalonRotado,'FaceVertexCData',color,'FaceColor','flat');
                patch(app.UIAxes,'faces',f,'vertices',piePuntaRotado,'FaceVertexCData',color,'FaceColor','flat');
                patch(app.UIAxes,'faces',f,'vertices',dedoPulgarRotado,'FaceVertexCData',color,'FaceColor','flat');
                patch(app.UIAxes,'faces',f,'vertices',dedoIndiceRotado,'FaceVertexCData',color,'FaceColor','flat');
                patch(app.UIAxes,'faces',f,'vertices',dedoCorazonRotado,'FaceVertexCData',color,'FaceColor','flat');
                patch(app.UIAxes,'faces',f,'vertices',dedoAnularRotado,'FaceVertexCData',color,'FaceColor','flat');
                patch(app.UIAxes,'faces',f,'vertices',dedoMeniqueRotado,'FaceVertexCData',color,'FaceColor','flat');


                tiempo = toc;
                pause(1/fm-tiempo);
                drawnow;
            end

        end

        % Button pushed function: BajadaButton
        function BajadaButtonPushed(app, event)
            main;
            fm =10;

            % Movimiento progresivo de subida
            for ii = 1:size(y_cb,2)
                Rtorso = rotationMatrix(0,0,0);

                cla(app.UIAxes);
                tic
                torsoRotado = applyRotationNx3(torso,sum(torso)/8,Rtorso);
                torsoRotado1 = applyRotationNx3(torso1,sum(torso)/8,Rtorso);

                patch(app.UIAxes,'faces',f,'vertices',torsoRotado,'FaceVertexCData',color,'FaceColor','flat');
                patch(app.UIAxes,'faces',f,'vertices',torsoRotado1,'FaceVertexCData',color,'FaceColor','flat');

                Rcadera = rotationMatrix(0,y_cb(ii),0);

                piernaSuperiorRotada1 = applyRotationNx3(applyRotationNx3(piernaSuperior1,...
                    (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                piernaSuperiorRotada2 = applyRotationNx3(applyRotationNx3(piernaSuperior2,...
                    (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);

                patch(app.UIAxes,'faces',f,'vertices',piernaSuperiorRotada1,'FaceVertexCData',color,'FaceColor','flat');
                patch(app.UIAxes,'faces',f,'vertices',piernaSuperiorRotada2,'FaceVertexCData',color,'FaceColor','flat');

                %         for jj = 1:size(y_rs,2)
                Rrodilla = rotationMatrix(0,y_rb(ii),0);

                piernaInferiorRotada1 = applyRotationNx3(applyRotationNx3(applyRotationNx3(Pierna1,...
                    sum(piernaSuperior1(1:4,:))/4,Rrodilla),(torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                piernaInferiorRotada2 = applyRotationNx3(applyRotationNx3(applyRotationNx3(Pierna2,...
                    sum(piernaSuperior1(1:4,:))/4,Rrodilla),(torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                piernaInferiorRotada3 = applyRotationNx3(applyRotationNx3(applyRotationNx3(Pierna3,...
                    sum(piernaSuperior1(1:4,:))/4,Rrodilla),(torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);

                patch(app.UIAxes,'faces',f,'vertices',piernaInferiorRotada1,'FaceVertexCData',color,'FaceColor','flat');
                patch(app.UIAxes,'faces',f,'vertices',piernaInferiorRotada2,'FaceVertexCData',color,'FaceColor','flat');
                patch(app.UIAxes,'faces',f,'vertices',piernaInferiorRotada3,'FaceVertexCData',color,'FaceColor','flat');

                %                 for kk = 1:size(y_ts,2)
                Rtobillo = rotationMatrix(0,y_tb(ii),0);

                pieTalonRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(pieDTalon,...
                    sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                    (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                piePuntaRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(pieDPunta,...
                    sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                    (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                dedoPulgarRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoGordo,...
                    sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                    (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                dedoIndiceRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoIndice,...
                    sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                    (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                dedoCorazonRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoCorazon,...
                    sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                    (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                dedoAnularRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoAnular,...
                    sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                    (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);
                dedoMeniqueRotado = applyRotationNx3(applyRotationNx3(applyRotationNx3(applyRotationNx3(dedoMenique,...
                    sum(Pierna1(1:4,:))/4,Rtobillo),sum(piernaSuperior1(1:4,:))/4,Rrodilla), ...
                    (torso(2,:)+torso(3,:))/2,Rcadera),sum(torso)/8,Rtorso);

                patch(app.UIAxes,'faces',f,'vertices',pieTalonRotado,'FaceVertexCData',color,'FaceColor','flat');
                patch(app.UIAxes,'faces',f,'vertices',piePuntaRotado,'FaceVertexCData',color,'FaceColor','flat');
                patch(app.UIAxes,'faces',f,'vertices',dedoPulgarRotado,'FaceVertexCData',color,'FaceColor','flat');
                patch(app.UIAxes,'faces',f,'vertices',dedoIndiceRotado,'FaceVertexCData',color,'FaceColor','flat');
                patch(app.UIAxes,'faces',f,'vertices',dedoCorazonRotado,'FaceVertexCData',color,'FaceColor','flat');
                patch(app.UIAxes,'faces',f,'vertices',dedoAnularRotado,'FaceVertexCData',color,'FaceColor','flat');
                patch(app.UIAxes,'faces',f,'vertices',dedoMeniqueRotado,'FaceVertexCData',color,'FaceColor','flat');


                tiempo = toc;
                pause(1/fm-tiempo);
                drawnow;
            end
        end

        % Button pushed function: PosicioninicialButton
        function PosicioninicialButtonPushed(app, event)
            main;
            %--------------------------------------------------------------------------
            %PIE
            cla(app.UIAxes);
            patch(app.UIAxes,'faces',f,'vertices',pieDTalon,'FaceVertexCData',color,'FaceColor','flat');
            patch(app.UIAxes,'faces',f,'vertices',pieDPunta,'FaceVertexCData',color,'FaceColor','flat');
            patch(app.UIAxes,'faces',f,'vertices',dedoGordo,'FaceVertexCData',color,'FaceColor','flat');
            patch(app.UIAxes,'faces',f,'vertices',dedoIndice,'FaceVertexCData',color,'FaceColor','flat');
            patch(app.UIAxes,'faces',f,'vertices',dedoCorazon,'FaceVertexCData',color,'FaceColor','flat');
            patch(app.UIAxes,'faces',f,'vertices',dedoAnular,'FaceVertexCData',color,'FaceColor','flat');
            patch(app.UIAxes,'faces',f,'vertices',dedoMenique,'FaceVertexCData',color,'FaceColor','flat');
            %-----------------------------------------------------------------------------
            %PIERNA INFERIOR
            patch(app.UIAxes,'faces',f,'vertices',Pierna1,'FaceVertexCData',color,'FaceColor','flat');
            patch(app.UIAxes,'faces',f,'vertices',Pierna2,'FaceVertexCData',color,'FaceColor','flat');
            patch(app.UIAxes,'faces',f,'vertices',Pierna3,'FaceVertexCData',color,'FaceColor','flat');
            %-----------------------------------------------------------------------------
            %PIERNA SUPERIOR
            patch(app.UIAxes,'faces',f,'vertices',piernaSuperior1,'FaceVertexCData',color,'FaceColor','flat');
            patch(app.UIAxes,'faces',f,'vertices',piernaSuperior2,'FaceVertexCData',color,'FaceColor','flat');
            % Definicion de partes del cuerpo (lado izquierdo
            patch(app.UIAxes,'faces',f,'vertices',torso1,'FaceVertexCData',color,'FaceColor','flat');
            patch(app.UIAxes,'faces',f,'vertices',torso,'FaceVertexCData',color,'FaceColor','flat');


        end

        % Button pushed function: GraficasButton
        function GraficasButtonPushed(app, event)
            global nombre apellidos edad peso;
            GUI_graficas;
            app.delete;
        end

        % Button pushed function: InformeButton
        function InformeButtonPushed(app, event)
            global nombre apellidos edad peso;
            GUI_informe;
            app.delete;
        end

        % Button pushed function: SensoresButton
        function SensoresButtonPushed(app, event)
            global nombre apellidos edad peso;
            GUI_sensores;
            app.delete;
        end

        % Button pushed function: VideoButton
        function VideoButtonPushed(app, event)
            global nombre apellidos edad peso;
            Marcadores;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageLogo = uiimage(app.GridLayout);
            app.ImageLogo.Tag = 'imageLogo';
            app.ImageLogo.ImageSource = 'SMA_1-PhotoRoom.png';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.InformeButton = uibutton(app.GridLayout4, 'push');
            app.InformeButton.ButtonPushedFcn = createCallbackFcn(app, @InformeButtonPushed, true);
            app.InformeButton.Tag = 'buttonInforme';
            app.InformeButton.Text = 'Informe';
            app.GraficasButton = uibutton(app.GridLayout4, 'push');
            app.GraficasButton.ButtonPushedFcn = createCallbackFcn(app, @GraficasButtonPushed, true);
            app.GraficasButton.Tag = 'buttonGraficas';
            app.GraficasButton.Text = 'Gráficas';
            app.SensoresButton = uibutton(app.GridLayout4, 'push');
            app.SensoresButton.ButtonPushedFcn = createCallbackFcn(app, @SensoresButtonPushed, true);
            app.SensoresButton.Text = 'Sensores';
            app.VideoButton = uibutton(app.GridLayout4, 'push');
            app.VideoButton.ButtonPushedFcn = createCallbackFcn(app, @VideoButtonPushed, true);
            app.VideoButton.Text = 'Video';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.NombreLabel = uilabel(app.GridLayout6);
            app.NombreLabel.Tag = 'labelNombre';
            app.NombreLabel.Text = 'Nombre:';
            app.EdadLabel = uilabel(app.GridLayout6);
            app.EdadLabel.Tag = 'labelEdad';
            app.EdadLabel.Text = 'Edad:';
            app.PesoLabel = uilabel(app.GridLayout6);
            app.PesoLabel.Tag = 'labelPeso';
            app.PesoLabel.Text = 'Peso:';
            app.FechaLabel = uilabel(app.GridLayout6);
            app.FechaLabel.Tag = 'labelFecha';
            app.FechaLabel.Text = 'Fecha:';
            app.NombreLabel2 = uilabel(app.GridLayout6);
            app.NombreLabel2.Tag = 'labelNombre2';
            app.NombreLabel2.Text = '';
            app.EdadLabel2 = uilabel(app.GridLayout6);
            app.EdadLabel2.Tag = 'labelEdad2';
            app.EdadLabel2.Text = '';
            app.PesoLabel2 = uilabel(app.GridLayout6);
            app.PesoLabel2.Tag = 'labelPeso2';
            app.PesoLabel2.Text = '';
            app.FechaLabel2 = uilabel(app.GridLayout6);
            app.FechaLabel2.Tag = 'labelFecha2';
            app.FechaLabel2.Text = '';
            app.ApellidosLabel = uilabel(app.GridLayout6);
            app.ApellidosLabel.Tag = 'labelApellidos';
            app.ApellidosLabel.Text = 'Apellidos:';
            app.ApellidosLabel2 = uilabel(app.GridLayout6);
            app.ApellidosLabel2.Tag = 'labelApellidos2';
            app.ApellidosLabel2.Text = '';
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.SubidaButton = uibutton(app.GridLayout7, 'push');
            app.SubidaButton.ButtonPushedFcn = createCallbackFcn(app, @SubidaButtonPushed, true);
            app.SubidaButton.Tag = 'buttonSubida';
            app.SubidaButton.Text = 'Subida';
            app.BajadaButton = uibutton(app.GridLayout7, 'push');
            app.BajadaButton.ButtonPushedFcn = createCallbackFcn(app, @BajadaButtonPushed, true);
            app.BajadaButton.Tag = 'buttonBajada';
            app.BajadaButton.Text = 'Bajada';
            app.PosicioninicialButton = uibutton(app.GridLayout7, 'push');
            app.PosicioninicialButton.ButtonPushedFcn = createCallbackFcn(app, @PosicioninicialButtonPushed, true);
            app.PosicioninicialButton.Tag = 'buttonPosicionInicial';
            app.PosicioninicialButton.Text = 'Posición inicial';
            app.VisualizaciondemovimientosLabel = uilabel(app.GridLayout7);
            app.VisualizaciondemovimientosLabel.Tag = 'labelVisualizacionMovimientos';
            app.VisualizaciondemovimientosLabel.Text = 'Visualización de movimientos';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.BienvenidoaSMRLabel = uilabel(app.GridLayout3);
            app.BienvenidoaSMRLabel.Tag = 'labelBienvenido';
            app.BienvenidoaSMRLabel.Text = 'Bienvenido a SMR';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout5);
            title(app.UIAxes, 'Simulación')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-130 100];
            app.UIAxes.YLim = [-10 60];
            app.UIAxes.ZLim = [-20 250];
            app.UIAxes.Tag = 'UIAxesAvatar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_avatarAlfa

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end