classdef GUI_graficas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        GridLayout5          matlab.ui.container.GridLayout
        BajadaAxes           matlab.ui.control.UIAxes
        SubidaAxes           matlab.ui.control.UIAxes
        GridLayout3          matlab.ui.container.GridLayout
        BienvenidoaSRSLabel  matlab.ui.control.Label
        GridLayout2          matlab.ui.container.GridLayout
        GridLayout7          matlab.ui.container.GridLayout
        TobilloButton        matlab.ui.control.Button
        RodillaButton        matlab.ui.control.Button
        CaderaButton         matlab.ui.control.Button
        GraficasLabel        matlab.ui.control.Label
        GridLayout6          matlab.ui.container.GridLayout
        ApellidosLabel2      matlab.ui.control.Label
        ApellidosLabel       matlab.ui.control.Label
        FechaLabel2          matlab.ui.control.Label
        PesoLabel2           matlab.ui.control.Label
        EdadLabel2           matlab.ui.control.Label
        NombreLabel2         matlab.ui.control.Label
        FechaLabel           matlab.ui.control.Label
        PesoLabel            matlab.ui.control.Label
        EdadLabel            matlab.ui.control.Label
        NombreLabel          matlab.ui.control.Label
        GridLayout4          matlab.ui.container.GridLayout
        VideoButton          matlab.ui.control.Button
        SensorButton         matlab.ui.control.Button
        AvatarButton         matlab.ui.control.Button
        InformeButton        matlab.ui.control.Button
        ImageLogo            matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global nombre apellidos edad peso;

            %load datos.mat nombre apellido edad peso;

            app.NombreLabel2.Text = strcat(nombre);
            app.ApellidosLabel2.Text = strcat(apellidos);
            app.EdadLabel2.Text = strcat(int2str(edad), " años");
            app.PesoLabel2.Text = strcat(num2str(peso) , "kg");

            formato = 'dd/mm/yy';
            app.FechaLabel2.Text = datestr(now,formato);
        end

        % Button pushed function: CaderaButton
        function CaderaButtonPushed(app, event)
            main;

            plot(app.SubidaAxes,cs);
            plot(app.BajadaAxes,cb,'r');
          
        end

        % Button pushed function: RodillaButton
        function RodillaButtonPushed(app, event)
            main;

            plot(app.SubidaAxes,rs);
            plot(app.BajadaAxes,rb,'r');

        end

        % Button pushed function: TobilloButton
        function TobilloButtonPushed(app, event)
            main;

            plot(app.SubidaAxes,ts);
            plot(app.BajadaAxes,tb,'r');
        end

        % Button pushed function: AvatarButton
        function AvatarButtonPushed(app, event)
            global nombre apellidos edad peso;
            GUI_avatarAlfa;
            app.delete;
        end

        % Button pushed function: InformeButton
        function InformeButtonPushed(app, event)
            global nombre apellidos edad peso;
            GUI_informe;
            app.delete;
        end

        % Button pushed function: SensorButton
        function SensorButtonPushed(app, event)
             global nombre apellidos edad peso;
             GUI_sensores;
             app.delete;
        end

        % Button pushed function: VideoButton
        function VideoButtonPushed(app, event)
            global nombre apellidos edad peso;
            Marcadores;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageLogo = uiimage(app.GridLayout);
            app.ImageLogo.Tag = 'imageLogo';
            app.ImageLogo.ImageSource = 'SMA_1-PhotoRoom.png';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.InformeButton = uibutton(app.GridLayout4, 'push');
            app.InformeButton.ButtonPushedFcn = createCallbackFcn(app, @InformeButtonPushed, true);
            app.InformeButton.Tag = 'buttonInforme';
            app.InformeButton.Text = 'Informe';
            app.AvatarButton = uibutton(app.GridLayout4, 'push');
            app.AvatarButton.ButtonPushedFcn = createCallbackFcn(app, @AvatarButtonPushed, true);
            app.AvatarButton.Tag = 'buttonAvatar';
            app.AvatarButton.Text = 'Avatar';
            app.SensorButton = uibutton(app.GridLayout4, 'push');
            app.SensorButton.ButtonPushedFcn = createCallbackFcn(app, @SensorButtonPushed, true);
            app.SensorButton.Text = 'Sensor';
            app.VideoButton = uibutton(app.GridLayout4, 'push');
            app.VideoButton.ButtonPushedFcn = createCallbackFcn(app, @VideoButtonPushed, true);
            app.VideoButton.Text = 'Video';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.NombreLabel = uilabel(app.GridLayout6);
            app.NombreLabel.Tag = 'labelNombre';
            app.NombreLabel.Text = 'Nombre:';
            app.EdadLabel = uilabel(app.GridLayout6);
            app.EdadLabel.Tag = 'labelEdad';
            app.EdadLabel.Text = 'Edad:';
            app.PesoLabel = uilabel(app.GridLayout6);
            app.PesoLabel.Tag = 'labelPeso';
            app.PesoLabel.Text = 'Peso:';
            app.FechaLabel = uilabel(app.GridLayout6);
            app.FechaLabel.Tag = 'labelFecha';
            app.FechaLabel.Text = 'Fecha:';
            app.NombreLabel2 = uilabel(app.GridLayout6);
            app.NombreLabel2.Tag = 'labelNombre2';
            app.NombreLabel2.Text = '';
            app.EdadLabel2 = uilabel(app.GridLayout6);
            app.EdadLabel2.Tag = 'labelEdad2';
            app.EdadLabel2.Text = '';
            app.PesoLabel2 = uilabel(app.GridLayout6);
            app.PesoLabel2.Tag = 'labelPeso2';
            app.PesoLabel2.Text = '';
            app.FechaLabel2 = uilabel(app.GridLayout6);
            app.FechaLabel2.Tag = 'labelFecha2';
            app.FechaLabel2.Text = '';
            app.ApellidosLabel = uilabel(app.GridLayout6);
            app.ApellidosLabel.Tag = 'labelApellidos';
            app.ApellidosLabel.Text = 'Apellidos:';
            app.ApellidosLabel2 = uilabel(app.GridLayout6);
            app.ApellidosLabel2.Tag = 'labelApellidos2';
            app.ApellidosLabel2.Text = '';
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.GraficasLabel = uilabel(app.GridLayout7);
            app.GraficasLabel.Tag = 'labelGraficas';
            app.GraficasLabel.Text = 'Gráficas';
            app.CaderaButton = uibutton(app.GridLayout7, 'push');
            app.CaderaButton.ButtonPushedFcn = createCallbackFcn(app, @CaderaButtonPushed, true);
            app.CaderaButton.Tag = 'buttonCadera';
            app.CaderaButton.Text = 'Cadera';
            app.RodillaButton = uibutton(app.GridLayout7, 'push');
            app.RodillaButton.ButtonPushedFcn = createCallbackFcn(app, @RodillaButtonPushed, true);
            app.RodillaButton.Tag = 'buttonRodilla';
            app.RodillaButton.Text = 'Rodilla';
            app.TobilloButton = uibutton(app.GridLayout7, 'push');
            app.TobilloButton.ButtonPushedFcn = createCallbackFcn(app, @TobilloButtonPushed, true);
            app.TobilloButton.Tag = 'buttonTobillo';
            app.TobilloButton.Text = 'Tobillo';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.BienvenidoaSRSLabel = uilabel(app.GridLayout3);
            app.BienvenidoaSRSLabel.Tag = 'labelBienvenido';
            app.BienvenidoaSRSLabel.Text = 'Bienvenido a SRS';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.SubidaAxes = uiaxes(app.GridLayout5);
            title(app.SubidaAxes, 'Subida')
            xlabel(app.SubidaAxes, 'Tiempo (ms)')
            ylabel(app.SubidaAxes, 'Ángulo (º)')
            zlabel(app.SubidaAxes, 'Z')
            app.SubidaAxes.Tag = 'axesSubida';
            app.BajadaAxes = uiaxes(app.GridLayout5);
            title(app.BajadaAxes, 'Bajada')
            xlabel(app.BajadaAxes, 'Tiempo (ms)')
            ylabel(app.BajadaAxes, 'Ángulo (º)')
            zlabel(app.BajadaAxes, 'Z')
            app.BajadaAxes.Tag = 'axesBajada';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_graficas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end