classdef GUI_inicio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ImageLogo             matlab.ui.control.Image
        GridLayout            matlab.ui.container.GridLayout
        GridLayout4           matlab.ui.container.GridLayout
        AvisoLabel            matlab.ui.control.Label
        GridLayout5           matlab.ui.container.GridLayout
        PesokgEditField       matlab.ui.control.NumericEditField
        PesokgEditFieldLabel  matlab.ui.control.Label
        EdadEditField         matlab.ui.control.NumericEditField
        EdadEditFieldLabel    matlab.ui.control.Label
        ApellidosEditField    matlab.ui.control.EditField
        ApellidosLabel        matlab.ui.control.Label
        NombreEditField       matlab.ui.control.EditField
        NombreEditFieldLabel  matlab.ui.control.Label
        GridLayout3           matlab.ui.container.GridLayout
        PorfavorrellenelossiguientescamposLabel  matlab.ui.control.Label
        BienvenidoaSMRLabel   matlab.ui.control.Label
        GridLayout2           matlab.ui.container.GridLayout
        GridLayout6           matlab.ui.container.GridLayout
        VideoButton           matlab.ui.control.Button
        SensoresButton        matlab.ui.control.Button
        InformeButton         matlab.ui.control.Button
        GrficasButton         matlab.ui.control.Button
        AvatarButton          matlab.ui.control.Button
        LabelInfo             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GrficasButton
        function GrficasButtonPushed(app, event)
            global nombre apellidos edad peso;

            nombre = app.NombreEditField.Value;
            apellidos = app.ApellidosEditField.Value;
            edad = app.EdadEditField.Value;
            peso = app.PesokgEditField.Value;

            caracteres_validos = '^[a-zA-Z\s]*$';

            if isempty(nombre) || isempty(apellidos)
               app.AvisoLabel.Text = 'Para continuar asegúrese de que todos los campos están rellenos';
            elseif edad <= 0 
                app.AvisoLabel.Text = 'Asegúrese de que la edad introducida no es menor o igual a 0';
            elseif edad >= 150 
                app.AvisoLabel.Text = 'Asegúrese de que la edad introducida no es mayor o igual a 150';    
            elseif peso <= 0
               app.AvisoLabel.Text = 'Asegúrese de que el peso introducido no es menor o igual a 0';
            elseif peso >= 300
               app.AvisoLabel.Text = 'Asegúrese de que el peso introducido no es mayor o igual a 300';
            elseif (size(regexp(nombre,caracteres_validos,'match')) == 0)
               app.AvisoLabel.Text = 'Asegúrese de que el nombre no contenga números o caracteres especiales';
            elseif (size(regexp(apellidos,caracteres_validos,'match')) == 0)
               app.AvisoLabel.Text = 'Asegúrese de que el apellido no contenga números o caracteres especiales';
            else    
               %save datos.mat nombre apellido edad peso;
               GUI_graficas;
               app.delete;
            end
        end

        % Button pushed function: AvatarButton
        function AvatarButtonPushed(app, event)
            global nombre apellidos edad peso;

            nombre = app.NombreEditField.Value;
            apellidos = app.ApellidosEditField.Value;
            edad = app.EdadEditField.Value;
            peso = app.PesokgEditField.Value;

            caracteres_validos = '^[a-zA-Z\s]*$';

            if isempty(nombre) || isempty(apellidos)
               app.AvisoLabel.Text = 'Para continuar asegúrese de que todos los campos están rellenos';
            elseif edad <= 0
               app.AvisoLabel.Text = 'Asegúrese de que la edad introducida no es menor que 0';
            elseif edad >= 150
               app.AvisoLabel.Text = 'Asegúrese de que la edad introducida no es mayor que 150';
            elseif peso <= 0
               app.AvisoLabel.Text = 'Asegúrese de que el peso introducido no es menor que 0';
            elseif peso >= 300
               app.AvisoLabel.Text = 'Asegúrese de que el peso introducido no es mayor que 300';
            elseif (size(regexp(nombre,caracteres_validos,'match')) == 0)
               app.AvisoLabel.Text = 'Asegúrese de que el nombre no contenga números o caracteres especiales';
            elseif (size(regexp(apellidos,caracteres_validos,'match')) == 0)
               app.AvisoLabel.Text = 'Asegúrese de que el apellido no contenga números o caracteres especiales';
            else
               %save datos.mat nombre apellido edad peso;
               GUI_avatarAlfa;
               app.delete;
            end
        end

        % Button pushed function: InformeButton
        function InformeButtonPushed(app, event)
            global nombre apellidos edad peso;

            nombre = app.NombreEditField.Value;
            apellidos = app.ApellidosEditField.Value;
            edad = app.EdadEditField.Value;
            peso = app.PesokgEditField.Value;

            caracteres_validos = '^[a-zA-Z\s]*$';

            if isempty(nombre) || isempty(apellidos)
               app.AvisoLabel.Text = 'Para continuar asegúrese de que todos los campos están rellenos';
            elseif edad == 0 
                app.AvisoLabel.Text = 'Asegúrese de que la edad introducida no es 0';
            elseif peso == 0
               app.AvisoLabel.Text = 'Asegúrese de que el peso introducido no es 0';
            elseif (size(regexp(nombre,caracteres_validos,'match')) == 0)
               app.AvisoLabel.Text = 'Asegúrese de que el nombre no contenga números o caracteres especiales';
            elseif (size(regexp(apellidos,caracteres_validos,'match')) == 0)
               app.AvisoLabel.Text = 'Asegúrese de que el apellido no contenga números o caracteres especiales';
            else
               %save datos.mat nombre apellido edad peso;
               GUI_informe;
               app.delete;
            end
        end

        % Button pushed function: SensoresButton
        function SensoresButtonPushed(app, event)
            global nombre apellidos edad peso;
            GUI_sensores;
            app.delete;
        end

        % Button pushed function: VideoButton
        function VideoButtonPushed(app, event)
           global nombre apellidos edad peso;
            Marcadores;
            app.delete; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.LabelInfo = uilabel(app.GridLayout2);
            app.LabelInfo.Tag = 'labelInfo';
            app.LabelInfo.Text = 'Tras rellenar las campos, elija una opción de las que se muestran en el menú inferior';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.AvatarButton = uibutton(app.GridLayout6, 'push');
            app.AvatarButton.ButtonPushedFcn = createCallbackFcn(app, @AvatarButtonPushed, true);
            app.AvatarButton.Tag = 'buttonAvatar';
            app.AvatarButton.Text = 'Avatar';
            app.GrficasButton = uibutton(app.GridLayout6, 'push');
            app.GrficasButton.ButtonPushedFcn = createCallbackFcn(app, @GrficasButtonPushed, true);
            app.GrficasButton.Tag = 'buttonGráficas';
            app.GrficasButton.Text = 'Gráficas';
            app.InformeButton = uibutton(app.GridLayout6, 'push');
            app.InformeButton.ButtonPushedFcn = createCallbackFcn(app, @InformeButtonPushed, true);
            app.InformeButton.Tag = 'buttonInforme';
            app.InformeButton.Text = 'Informe';
            app.SensoresButton = uibutton(app.GridLayout6, 'push');
            app.SensoresButton.ButtonPushedFcn = createCallbackFcn(app, @SensoresButtonPushed, true);
            app.SensoresButton.Text = 'Sensores';
            app.VideoButton = uibutton(app.GridLayout6, 'push');
            app.VideoButton.ButtonPushedFcn = createCallbackFcn(app, @VideoButtonPushed, true);
            app.VideoButton.Text = 'Video';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.BienvenidoaSMRLabel = uilabel(app.GridLayout3);
            app.BienvenidoaSMRLabel.Tag = 'labelBienvenido';
            app.BienvenidoaSMRLabel.Text = 'Bienvenido a SMR';
            app.PorfavorrellenelossiguientescamposLabel = uilabel(app.GridLayout3);
            app.PorfavorrellenelossiguientescamposLabel.Tag = 'labelCampos';
            app.PorfavorrellenelossiguientescamposLabel.Text = 'Por favor, rellene los siguientes campos';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.NombreEditFieldLabel = uilabel(app.GridLayout5);
            app.NombreEditFieldLabel.Tag = 'labelNombre';
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.GridLayout5, 'text');
            app.NombreEditField.Tag = 'textFieldNombre';
            app.ApellidosLabel = uilabel(app.GridLayout5);
            app.ApellidosLabel.Tag = 'labelApellidos';
            app.ApellidosLabel.Text = 'Apellidos';
            app.ApellidosEditField = uieditfield(app.GridLayout5, 'text');
            app.ApellidosEditField.Tag = 'labelApellidos';
            app.EdadEditFieldLabel = uilabel(app.GridLayout5);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.EdadEditField = uieditfield(app.GridLayout5, 'numeric');
            app.EdadEditField.Limits = [0 150];
            app.EdadEditField.ValueDisplayFormat = '%.0f';
            app.EdadEditField.Tag = 'NumericFieldEdad';
            app.PesokgEditFieldLabel = uilabel(app.GridLayout5);
            app.PesokgEditFieldLabel.Text = 'Peso (kg)';
            app.PesokgEditField = uieditfield(app.GridLayout5, 'numeric');
            app.PesokgEditField.Limits = [0 300];
            app.PesokgEditField.Tag = 'NumericFieldPeso';
            app.AvisoLabel = uilabel(app.GridLayout4);
            app.AvisoLabel.Tag = 'labelAviso';
            app.AvisoLabel.Text = '';
            app.ImageLogo = uiimage(app.UIFigure);
            app.ImageLogo.Tag = 'imageLogo';
            app.ImageLogo.ImageSource = 'SMA_1-PhotoRoom.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_inicio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end