classdef Marcadores < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        GridLayout12              matlab.ui.container.GridLayout
        SensoresButton            matlab.ui.control.Button
        GrficasButton             matlab.ui.control.Button
        AvatarButton              matlab.ui.control.Button
        GridLayout11              matlab.ui.container.GridLayout
        GridLayout10              matlab.ui.container.GridLayout
        BpercentageEditField      matlab.ui.control.NumericEditField
        Label_4                   matlab.ui.control.Label
        BmissedEditField          matlab.ui.control.NumericEditField
        MissedEditField_3Label    matlab.ui.control.Label
        GridLayout9               matlab.ui.container.GridLayout
        GpercentageEditField      matlab.ui.control.NumericEditField
        Label_3                   matlab.ui.control.Label
        GmissedEditField          matlab.ui.control.NumericEditField
        MissedEditField_2Label    matlab.ui.control.Label
        GridLayout8               matlab.ui.container.GridLayout
        RpercentageEditField      matlab.ui.control.NumericEditField
        Label_2                   matlab.ui.control.Label
        RmissedEditField          matlab.ui.control.NumericEditField
        MissedEditFieldLabel      matlab.ui.control.Label
        GridLayout7               matlab.ui.container.GridLayout
        AllFrame                  matlab.ui.control.UIAxes
        GridLayout6               matlab.ui.container.GridLayout
        Blue                      matlab.ui.control.UIAxes
        GridLayout5               matlab.ui.container.GridLayout
        Green                     matlab.ui.control.UIAxes
        GridLayout4               matlab.ui.container.GridLayout
        Red                       matlab.ui.control.UIAxes
        GridLayout3               matlab.ui.container.GridLayout
        TotalFrameEditField       matlab.ui.control.NumericEditField
        TotalFrameEditFieldLabel  matlab.ui.control.Label
        TotalperEditField         matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        GridLayout2               matlab.ui.container.GridLayout
        Image                     matlab.ui.control.Image
        PlayButton                matlab.ui.control.Button
        Frame                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Rmissedframes =0;
        Bmissedframes =0;
        Gmissedframes =0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
             v = VideoReader('MTD2.mp4');
            for i = 1:v.NumFrames
            app.TotalFrameEditField.Value = i;
            app.TotalperEditField.Value = (i/v.NumFrames)*100;
                f = readFrame(v);
                f = f(:,1:1400,:);
                [BB,m1] =createMaskR(f);
                [BB2,m2] = createMaskG(f);
                [BB3, m3] =createMaskB(f);

                BB_ao= bwareaopen(BB,500);
                BB2_ao = bwareaopen(BB2,500);
                BB3_ao = bwareaopen(BB3, 500);

                se = strel('disk',30);
                BB_cl =imclose(BB_ao, se);
                BB2_cl = imclose(BB2_ao, se);
                BB3_cl = imclose(BB3_ao, se);

%                 m1 = bsxfun(@times, f, cast(BB_cl, 'like', f));
%                 m2 = bsxfun(@times, f, cast(BB2_cl, 'like', f));
%                 m3 = bsxfun(@times, f, cast(BB3_cl, 'like', f));
                m1 = BB_cl;
                m2 = BB2_cl;
                m3 = BB3_cl;

                sR = sum(m1,'all');
                if sR == 0
                    app.Rmissedframes = app.Rmissedframes+1;
                    app.RmissedEditField.Value = app.Rmissedframes;
                end

                sG = sum(m2,'all');
                if sG ==0
                    app.Gmissedframes = app.Gmissedframes +1;
                    app.GmissedEditField.Value = app.Gmissedframes;
                end

                sB = sum(m3,'all');
                if sB == 0
                    app.Bmissedframes = app.Bmissedframes +1;
                    app.BmissedEditField.Value = app.Bmissedframes;
                end

                %porcentaje
                app.RpercentageEditField.Value = (app.Rmissedframes/i)*100;
                app.GpercentageEditField.Value = (app.Gmissedframes/i)*100;
                app.BpercentageEditField.Value = (app.Bmissedframes/i)*100;


                imshow(f,'Parent',app.Frame);
                imshow(m1, 'Parent',app.Red);
                imshow(m2, 'Parent', app.Green);
                imshow(m3, 'Parent',app.Blue);

                mAll = m1 | m2 | m3;
                mAllc = bsxfun(@times, f, cast(mAll, 'like', f));
                imshow(mAllc, 'Parent', app.AllFrame);
                drawnow;
               
                %centroides

                [labeledImage, numberOfRegions] = bwlabel(mAll);
                if numberOfRegions >=1
                    stats = regionprops(labeledImage, 'BoundingBox', 'Centroid');
                    for r = 1:numberOfRegions
                        thisCentroid = stats(r).Centroid;
                        hold(app.AllFrame, 'on')
                        hSpot = plot(app.AllFrame, thisCentroid(1),thisCentroid(2), 'r+', 'MarkerSize', 10, 'LineWidth',2);
                        drawnow
                        hold(app.AllFrame, 'off')
                    end
                end
              
            end
        end

        % Button pushed function: AvatarButton
        function AvatarButtonPushed(app, event)
             global nombre apellidos edad peso;
            GUI_avatarAlfa;
            app.delete;
        end

        % Button pushed function: GrficasButton
        function GrficasButtonPushed(app, event)
             global nombre apellidos edad peso;
            GUI_graficas;
            app.delete;
        end

        % Button pushed function: SensoresButton
        function SensoresButtonPushed(app, event)
             global nombre apellidos edad peso;
            GUI_sensores;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Frame = uiaxes(app.GridLayout);
            title(app.Frame, 'Frames')
            app.Frame.XTick = [];
            app.Frame.YTick = [];
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.PlayButton = uibutton(app.GridLayout2, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = 'SMA_1-PhotoRoom.png';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '%';
            app.TotalperEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TotalFrameEditFieldLabel = uilabel(app.GridLayout3);
            app.TotalFrameEditFieldLabel.Text = 'Total Frame';
            app.TotalFrameEditField = uieditfield(app.GridLayout3, 'numeric');
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.Red = uiaxes(app.GridLayout4);
            title(app.Red, 'Red')
            app.Red.XTick = [];
            app.Red.YTick = [];
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.Green = uiaxes(app.GridLayout5);
            title(app.Green, 'Green')
            app.Green.XTick = [];
            app.Green.YTick = [];
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.Blue = uiaxes(app.GridLayout6);
            title(app.Blue, 'Blue')
            app.Blue.XTick = [];
            app.Blue.YTick = [];
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.AllFrame = uiaxes(app.GridLayout7);
            title(app.AllFrame, 'All Frames')
            app.AllFrame.XTick = [];
            app.AllFrame.YTick = [];
            app.GridLayout8 = uigridlayout(app.GridLayout);
            app.MissedEditFieldLabel = uilabel(app.GridLayout8);
            app.MissedEditFieldLabel.Text = 'Missed';
            app.RmissedEditField = uieditfield(app.GridLayout8, 'numeric');
            app.Label_2 = uilabel(app.GridLayout8);
            app.Label_2.Text = '%';
            app.RpercentageEditField = uieditfield(app.GridLayout8, 'numeric');
            app.GridLayout9 = uigridlayout(app.GridLayout);
            app.MissedEditField_2Label = uilabel(app.GridLayout9);
            app.MissedEditField_2Label.Text = 'Missed';
            app.GmissedEditField = uieditfield(app.GridLayout9, 'numeric');
            app.Label_3 = uilabel(app.GridLayout9);
            app.Label_3.Text = '%';
            app.GpercentageEditField = uieditfield(app.GridLayout9, 'numeric');
            app.GridLayout10 = uigridlayout(app.GridLayout);
            app.MissedEditField_3Label = uilabel(app.GridLayout10);
            app.MissedEditField_3Label.Text = 'Missed';
            app.BmissedEditField = uieditfield(app.GridLayout10, 'numeric');
            app.Label_4 = uilabel(app.GridLayout10);
            app.Label_4.Text = '%';
            app.BpercentageEditField = uieditfield(app.GridLayout10, 'numeric');
            app.GridLayout11 = uigridlayout(app.GridLayout);
            app.GridLayout12 = uigridlayout(app.GridLayout);
            app.AvatarButton = uibutton(app.GridLayout12, 'push');
            app.AvatarButton.ButtonPushedFcn = createCallbackFcn(app, @AvatarButtonPushed, true);
            app.AvatarButton.Text = 'Avatar';
            app.GrficasButton = uibutton(app.GridLayout12, 'push');
            app.GrficasButton.ButtonPushedFcn = createCallbackFcn(app, @GrficasButtonPushed, true);
            app.GrficasButton.Text = 'Gráficas';
            app.SensoresButton = uibutton(app.GridLayout12, 'push');
            app.SensoresButton.ButtonPushedFcn = createCallbackFcn(app, @SensoresButtonPushed, true);
            app.SensoresButton.Text = 'Sensores';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Marcadores

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end